classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataacquisitionforTrainingPanel  matlab.ui.container.Panel
        RecordButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        FeatureExtractionPanel          matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        ExtractFeaturesButton           matlab.ui.control.Button
        UnsupervisedClusteringPanel     matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        StartTrainingButton             matlab.ui.control.Button
        SpeaktotestpersonidentityPanel  matlab.ui.container.Panel
        RecordButton_2                  matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        WriteMeetingMinutestoFilePanel  matlab.ui.container.Panel
        WriteFileButton                 matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        FLabel                          matlab.ui.control.Label
        StatusLabel_2                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        features % Description
        y % sound file
        gmfit% Description
        trainDatastore % Description
        X
         a
        gmfit2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.StatusLabel.Text='Status: Recording';
            drawnow
            run("file8_speech2textWithGoogle.m")
            
        end

        % Button pushed function: ExtractFeaturesButton
        function ExtractFeaturesButtonPushed(app, event)
         app.ExtractFeaturesButton.BusyAction
         run("file8_1_speech2feats.m")
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
             
             run("file8_som.m")
             
        end

        % Button pushed function: RecordButton_2
        function RecordButton_2Pushed(app, event)
           run("file9_test2retrainAndIdentify.m")
        end

        % Button pushed function: WriteFileButton
        function WriteFileButtonPushed(app, event)
            run("file10_write2file.m")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataacquisitionforTrainingPanel = uipanel(app.UIFigure);
            app.DataacquisitionforTrainingPanel.Title = 'Data acquisition for Training';
            app.RecordButton = uibutton(app.DataacquisitionforTrainingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.Label = uilabel(app.DataacquisitionforTrainingPanel);
            app.Label.Text = 'Press the button to start recording. This will record a  6 minute Conference Meeting.';
            app.FeatureExtractionPanel = uipanel(app.UIFigure);
            app.FeatureExtractionPanel.Title = 'Feature Extraction';
            app.Label_2 = uilabel(app.FeatureExtractionPanel);
            app.Label_2.Text = 'Press the button to start feature extraction. This will extract 14 features :';
            app.ExtractFeaturesButton = uibutton(app.FeatureExtractionPanel, 'push');
            app.ExtractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFeaturesButtonPushed, true);
            app.ExtractFeaturesButton.Text = 'Extract Features';
            app.UnsupervisedClusteringPanel = uipanel(app.UIFigure);
            app.UnsupervisedClusteringPanel.Title = 'Unsupervised Clustering';
            app.Label_3 = uilabel(app.UnsupervisedClusteringPanel);
            app.Label_3.Text = 'Press the button to start training Gausian Mixture Model.';
            app.StartTrainingButton = uibutton(app.UnsupervisedClusteringPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training ';
            app.SpeaktotestpersonidentityPanel = uipanel(app.UIFigure);
            app.SpeaktotestpersonidentityPanel.Title = 'Speak to test person identity';
            app.RecordButton_2 = uibutton(app.SpeaktotestpersonidentityPanel, 'push');
            app.RecordButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_2Pushed, true);
            app.RecordButton_2.Text = 'Record';
            app.Label_4 = uilabel(app.SpeaktotestpersonidentityPanel);
            app.Label_4.Text = 'Press the button to start recording for a short interval and use Google Speech2Text.';
            app.WriteMeetingMinutestoFilePanel = uipanel(app.UIFigure);
            app.WriteMeetingMinutestoFilePanel.Title = 'Write Meeting Minutes to File';
            app.WriteFileButton = uibutton(app.WriteMeetingMinutestoFilePanel, 'push');
            app.WriteFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteFileButtonPushed, true);
            app.WriteFileButton.Text = 'Write File';
            app.Label_5 = uilabel(app.WriteMeetingMinutestoFilePanel);
            app.Label_5.Text = 'Press the button to save the Speech to Text result on a Text File with Person Info.';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F:';
            app.StatusLabel_2 = uilabel(app.UIFigure);
            app.StatusLabel_2.Text = 'Status:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'trainedNet.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end